classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        RunButton                      matlab.ui.control.Button
        EdgedetectionoperatorDropDown  matlab.ui.control.DropDown
        EdgedetectionoperatorDropDownLabel  matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        ObjectSegmentationTab          matlab.ui.container.Tab
        CustomthresholdEditField       matlab.ui.control.NumericEditField
        CustomthresholdEditFieldLabel  matlab.ui.control.Label
        ThresholdDropDown              matlab.ui.control.DropDown
        ThresholdDropDownLabel         matlab.ui.control.Label
        LineDetectionTab               matlab.ui.container.Tab
        VotethresholdSlider            matlab.ui.control.Slider
        VotethresholdSliderLabel       matlab.ui.control.Label
        LinecolorColorPicker           matlab.ui.control.ColorPicker
        LinecolorColorPickerLabel      matlab.ui.control.Label
        FilenameLabel                  matlab.ui.control.Label
        ChoosefileButton               matlab.ui.control.Button
        ObjectSegmentationLineDetectionLabel  matlab.ui.control.Label
        InputImageDisplay              matlab.ui.control.UIAxes
        EdgeImageDisplay               matlab.ui.control.UIAxes
        OutputImageDisplay             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        InputImage
        EdgeImage
        OutputImage
    end
  

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ChoosefileButton
        function ChoosefileButtonPushed(app, event)
            [filename, path] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files (*.jpg, *.png, *.bmp)'});
            if isequal(filename, 0)
                disp('User cancelled the operation');
            else
                cla(app.InputImageDisplay);

                app.InputImage = im2double(imread(fullfile(path, filename)));
                app.FilenameLabel.Text = filename;
        
                imshow(app.InputImage, 'Parent', app.InputImageDisplay);
                app.RunButton.Enable = 'on';
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            [M, N] = size(app.InputImage, [1 2]);
            edgeOperator = lower(app.EdgedetectionoperatorDropDown.Value);

            thresholdType = lower(app.ThresholdDropDown.Value);
            threshold = app.CustomthresholdEditField.Value;
            app.EdgeImage = edgeDetection(app.InputImage, edgeOperator, thresholdType, threshold);
            imshow(app.EdgeImage, 'Parent', app.EdgeImageDisplay);

            switch app.TabGroup.SelectedTab
                case app.ObjectSegmentationTab
                    app.OutputImage = segmentation(app.InputImage, app.EdgeImage);

                case app.LineDetectionTab
                    result = lineDetection(app.EdgeImage);

                    threshold = app.VotethresholdSlider.Value * max(result, [], 'all');
                    lineMask = lineDraw(result, size(app.InputImage, 1), size(app.InputImage, 2), threshold);

                    colorOverlay = zeros([M N 3]);
                    colorOverlay(:, :, 1) = app.LinecolorColorPicker.Value(1);
                    colorOverlay(:, :, 2) = app.LinecolorColorPicker.Value(2);
                    colorOverlay(:, :, 3) = app.LinecolorColorPicker.Value(3);
                    app.OutputImage = im2double(app.InputImage) .* (1 - lineMask) + colorOverlay .* lineMask;
            end

            imshow(app.OutputImage, 'Parent', app.OutputImageDisplay);
        end

        % Value changed function: ThresholdDropDown
        function ThresholdDropDownValueChanged(app, event)
            value = app.ThresholdDropDown.Value;
            if strcmpi(value, 'Input')
                app.CustomthresholdEditField.Enable = "on";
            else
                app.CustomthresholdEditField.Enable = "off";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OutputImageDisplay = uiaxes(app.UIFigure);
            title(app.OutputImageDisplay, 'Output Image')
            app.OutputImageDisplay.XTick = [];
            app.OutputImageDisplay.YTick = [];
            app.EdgeImageDisplay = uiaxes(app.UIFigure);
            title(app.EdgeImageDisplay, 'Edge Image')
            app.EdgeImageDisplay.XTick = [];
            app.EdgeImageDisplay.YTick = [];
            app.InputImageDisplay = uiaxes(app.UIFigure);
            title(app.InputImageDisplay, 'Input Image')
            app.InputImageDisplay.XTick = [];
            app.InputImageDisplay.YTick = [];
            app.ObjectSegmentationLineDetectionLabel = uilabel(app.UIFigure);
            app.ObjectSegmentationLineDetectionLabel.Text = {'Object Segmentation & '; 'Line Detection'};
            app.ChoosefileButton = uibutton(app.UIFigure, 'push');
            app.ChoosefileButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosefileButtonPushed, true);
            app.ChoosefileButton.Text = 'Choose file';
            app.FilenameLabel = uilabel(app.UIFigure);
            app.FilenameLabel.Text = 'File name';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ObjectSegmentationTab = uitab(app.TabGroup);
            app.ObjectSegmentationTab.Title = 'Object Segmentation';
            app.ThresholdDropDownLabel = uilabel(app.ObjectSegmentationTab);
            app.ThresholdDropDownLabel.Text = 'Threshold';
            app.ThresholdDropDown = uidropdown(app.ObjectSegmentationTab);
            app.ThresholdDropDown.Items = {'Global', 'Adaptive', 'Otsu', 'Input'};
            app.ThresholdDropDown.ValueChangedFcn = createCallbackFcn(app, @ThresholdDropDownValueChanged, true);
            app.ThresholdDropDown.Value = 'Global';
            app.CustomthresholdEditFieldLabel = uilabel(app.ObjectSegmentationTab);
            app.CustomthresholdEditFieldLabel.Enable = 'off';
            app.CustomthresholdEditFieldLabel.Text = 'Custom threshold';
            app.CustomthresholdEditField = uieditfield(app.ObjectSegmentationTab, 'numeric');
            app.CustomthresholdEditField.Limits = [0 1];
            app.CustomthresholdEditField.Enable = 'off';
            app.CustomthresholdEditField.Value = 0.1;
            app.LineDetectionTab = uitab(app.TabGroup);
            app.LineDetectionTab.Title = 'Line Detection';
            app.LinecolorColorPickerLabel = uilabel(app.LineDetectionTab);
            app.LinecolorColorPickerLabel.Text = 'Line color';
            app.LinecolorColorPicker = uicolorpicker(app.LineDetectionTab);
            app.VotethresholdSliderLabel = uilabel(app.LineDetectionTab);
            app.VotethresholdSliderLabel.Text = 'Vote threshold';
            app.VotethresholdSlider = uislider(app.LineDetectionTab);
            app.VotethresholdSlider.Limits = [0 1];
            app.VotethresholdSlider.Value = 0.5;
            app.EdgedetectionoperatorDropDownLabel = uilabel(app.UIFigure);
            app.EdgedetectionoperatorDropDownLabel.Text = 'Edge detection operator';
            app.EdgedetectionoperatorDropDown = uidropdown(app.UIFigure);
            app.EdgedetectionoperatorDropDown.Items = {'Laplace', 'LoG', 'Sobel', 'Prewitt', 'Roberts', 'Canny'};
            app.EdgedetectionoperatorDropDown.Placeholder = 'Choose operator';
            app.EdgedetectionoperatorDropDown.Value = 'Laplace';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Enable = 'off';
            app.RunButton.Text = 'Run';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end